classdef GitMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        emailEditField                 matlab.ui.control.EditField
        GHemailaddressEditFieldLabel   matlab.ui.control.Label
        usernameEditField              matlab.ui.control.EditField
        GHUsernameEditFieldLabel       matlab.ui.control.Label
        DownloadsPanel                 matlab.ui.container.Panel
        downloadslabel                 matlab.ui.control.Label
        GHCLIButton                    matlab.ui.control.Button
        GHDesktopButton                matlab.ui.control.Button
        GitButton                      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        OKButton2                      matlab.ui.control.Button
        OKButton1                      matlab.ui.control.Button
        userInput                      matlab.ui.control.EditField
        TextArea                       matlab.ui.control.EditField
        LinkCodetoGithubButton         matlab.ui.control.Button
        ConnectGHButton                matlab.ui.control.Button
        CloneRepositoryButton          matlab.ui.control.Button
        NewRepoTab                     matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        CreateanewrepositoryLabel      matlab.ui.control.Label
        DescriptionEditField           matlab.ui.control.EditField
        VisibilityDropDown             matlab.ui.control.DropDown
        VisibilityLabel                matlab.ui.control.Label
        RepositoryNameEditField        matlab.ui.control.EditField
        RepositoryNameEditFieldLabel   matlab.ui.control.Label
        OwnerDropDownLabel             matlab.ui.control.Label
        OwnerDropDown                  matlab.ui.control.DropDown
        LicenseDropDown                matlab.ui.control.DropDown
        license                        matlab.ui.control.CheckBox
        README                         matlab.ui.control.CheckBox
        gitignore                      matlab.ui.control.CheckBox
        nameAvailability               matlab.ui.control.Label
        charactersLabel                matlab.ui.control.Label
        DescriptionEditFieldLabel      matlab.ui.control.Label
        slash                          matlab.ui.control.Label
        Create                         matlab.ui.control.Button
        IgnoreTab                      matlab.ui.container.Tab
        SelectwhichfilestoignoreLabel  matlab.ui.control.Label
        FileTypesListBox               matlab.ui.control.ListBox
        FileTypesListBoxLabel          matlab.ui.control.Label
        NotListedEdit                  matlab.ui.control.EditField
        FiletypenotlistedEditFieldLabel  matlab.ui.control.Label
        AddButton                      matlab.ui.control.Button
        DoneButton                     matlab.ui.control.Button
        SelectFilesButton              matlab.ui.control.Button
        SelectedFiles                  matlab.ui.control.TextArea
    end

    properties (Access = private)
        repoRemoved = false; %flag to now if a remote repo has been unlinked with local repo
    end
    
    methods (Access = private)

        function InstallProgram(app, download)
            if ismac
                % Check to see if MacPorts is installed
                [~, result] = system('command -v port');

                if isempty(result) % if not
                    % Install MacPorts (assuming the user has Xcode and the command line tools installed)
                    system('curl -O https://distfiles.macports.org/MacPorts/MacPorts-2.8.1.tar.bz2');
                    system('tar xjvf MacPorts-2.8.1.tar.bz2');
                    cd('MacPorts-2.8.1');
                    system('./configure && make && sudo make install');
                    cd('..');
                    system('rm -rf MacPorts-2.8.1 MacPorts-2.8.1.tar.bz2');
                    system('sudo port -v selfupdate');
                end

                if strcmp(download, 'Git')
                    [Installstatus, error] = system('sudo port install git');
                elseif strcmp(download, 'Desktop')
                    % MacPorts does not have a 'cask' equivalent, so we'll need to install GitHub Desktop manually
                    system('curl -o GitHubDesktop.zip -L https://central.github.com/deployments/desktop/desktop/latest/darwin');
                    system('unzip GitHubDesktop.zip -d /Applications');
                    system('rm GitHubDesktop.zip');
                    Installstatus = 0; % Assuming the installation is successful
                    error = '';
                elseif strcmp(download, 'CLI')
                    [status, ~] = system('command -v gh'); % check if already installed
                    if status == 0 % GitHub CLI is installed. Update instead
                        [Installstatus, ~] = system('sudo port upgrade gh');
                    else
                        [Installstatus, error] = system('sudo port install gh');
                    end
                end
            elseif isunix
                system('sudo apt update'); % Update the package index

                if strcmp(download, 'Git')
                    [Installstatus, error] = system('sudo apt install git -y');
                elseif strcmp(download, 'Desktop')
                    [Installstatus, error] = system('sudo apt install github-desktop');
                elseif strcmp(download,'CLI')
                    [status, ~] = system('which snap');
                    if status ~= 0 %if snap is not installed
                        system('sudo apt install snapd');
                        system('sudo apt install snapd-xdg-open'); %install class snap support
                    end
                    [Installstatus, error] = system('sudo snap install gh');
                end
            elseif ispc
                if strcmp(download, 'Git')
                    % Official Git for Windows (64-bit) latest installer
                    url = 'https://github.com/git-for-windows/git/releases/download/v2.51.0.windows.1/Git-2.51.0-64-bit.exe';

                    % Save to temp folder
                    installerFile = fullfile(tempdir, 'GitInstaller.exe');
                    %Download installer
                    websave(installerFile, url);

                    % Run silent install
                    [Installstatus, error] = system(['"', installerFile, '" /VERYSILENT']);

                elseif strcmp(download, 'Desktop')
                    % URL of GitHub Desktop installer (latest release from GitHub)
                    url = 'https://central.github.com/deployments/desktop/desktop/latest/win32';

                    % Where to save installer
                    installerFile = fullfile(tempdir, 'GitHubDesktopSetup.exe');
                    % Download installer
                    websave(installerFile, url);

                    % Run installer silently
                    [Installstatus, error] = system(['"', installerFile, '" /s']);
                elseif strcmp(download, 'CLI')
                    % Latest GitHub CLI Windows installer (64-bit)
                    url = 'https://github.com/cli/cli/releases/download/v2.78.0/gh_2.78.0_windows_amd64.msi';
                    installerFile = fullfile(tempdir, 'gh_installer.msi');
                    websave(installerFile, url);

                    % Run MSI installer silently
                    [Installstatus, error] = system(['msiexec /i "', installerFile, '" /quiet /norestart']);
                end

            else
                app.TextArea.Value = 'Unsupported operating system.';
                drawnow;
            end

            if Installstatus == 0
                app.TextArea.Value = 'Installation Complete!';
                drawnow;

                pause(3);

                if ~strcmp(download, 'Git')
                    app.TextArea.Value = 'If no other program is needed, please RESTART MATLAB.';
                    app.TextArea.FontSize = 11;
                    drawnow;
                else
                    return;
                end
            elseif Installstatus == 1603
                app.TextArea.Value = 'Please start MATLAB as Admin';
                drawnow;
            else
                app.TextArea.Value = 'Installation Failed';
                app.TextArea.Value = error;
                drawnow;
            end
        end

        function reset(app)
            app.emailEditField.Visible = 'off';
            app.usernameEditField.Visible = 'off';
            app.GHUsernameEditFieldLabel.Visible = 'off';
            app.GHemailaddressEditFieldLabel.Visible = 'off';
            app.OKButton1.Visible = 'off';
            app.OKButton1.Enable = 'off';
            app.OKButton2.Visible = 'off';
            app.OKButton2.Enable = 'off';
            app.userInput.Visible = 'off';
            app.userInput.Value = '';
            app.SelectedFiles.Value = '';
            app.FileTypesListBox.Value = '.7z';
            app.TextArea.Value = "";
            app.TextArea.FontColor = [0.65,0.65,0.65];
            drawnow;
        end

        function checkFileSize(~)
            % Max GitHub file size (bytes)
            maxSize = 100 * 1024 * 1024;

            % Find large files in repo
            [~, fileList] = system('git ls-files');
            files = strsplit(strtrim(fileList), newline);

            for i = 1:numel(files)
                if isfile(files{i})
                    fileInfo = dir(files{i});
                    if fileInfo.bytes > maxSize
                        [~, ~, ext] = fileparts(files{i});
                        fprintf('⚠️ File too large: %s (%.2f MB)\n', ...
                            files{i}, fileInfo.bytes/1024/1024);

                        if strcmpi(ext, '.exe')
                            fprintf(['.exe files should not be committed.\n' ...
                                '👉 Suggestion: remove from tracking and upload\n' ...
                                '   to GitHub Releases instead.\n']);
                            choice = input('Remove from tracking now? (y/n): ', 's');
                            if lower(choice) == 'y'
                                system(['git rm --cached "' files{i} '"']);
                                fprintf('Removed %s from git tracking.\n', files{i});
                            end
                        else
                            choice = input('File too large. Track with LFS (l) or remove (r)? ', 's');
                            if lower(choice) == 'l'
                                system('git lfs install');
                                system(['git lfs track "' ext '"']);
                                system('git add .gitattributes');
                                fprintf('Added %s to Git LFS tracking.\n', files{i});
                            elseif lower(choice) == 'r'
                                system(['git rm --cached "' files{i} '"']);
                                fprintf('Removed %s from git tracking.\n', files{i});
                            else
                                fprintf('Skipped handling of %s.\n', files{i});
                            end
                        end
                    end
                end
            end

            %             % Commit and push if clean
            %             system('git add .');
            %             system('git commit -m "Commit with size check"');
            %             system('git push -u origin main');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Git&Matlab'; %change the app title
            clc;

            % Check if an install state file exists (from previous session)
            stateFile = fullfile(tempdir, 'installState.mat');
            installedFromState = '';

            if isfile(stateFile)
                S = load(stateFile, 'download');
                installedFromState = S.download;

                switch installedFromState
                    case 'Git'
                        app.GitButton.BackgroundColor = [0.90,0.90,0.90];
                        app.GitButton.FontColor = [0.80,0.80,0.80];
                        app.Label.Visible = 'off';
                    case 'Desktop'
                        app.GHDesktopButton.BackgroundColor = [0.90,0.90,0.90];
                        app.GHDesktopButton.FontColor = [0.80,0.80,0.80];
                        app.Label.Visible = 'off';
                    case 'CLI'
                        app.GHCLIButton.BackgroundColor = [0.90,0.90,0.90];
                        app.GHCLIButton.FontColor = [0.80,0.80,0.80];
                        app.CloneRepositoryButton.Enable = 'on';
                        app.ConnectGHButton.Enable = 'on';
                        app.LinkCodetoGithubButton.Enable = 'on';

                end
                delete(stateFile); % cleanup so it doesn’t persist forever
            end

            % Check to see if the program is installed. If it is, grey out
            % the button but leave it enable in case user wants to update
            % the program
            if isempty(installedFromState)

                if ispc
                    [Gstatus, ~] = system('where git');
                    [GHstatus, ~] = system('where github.bat');
                    [CLIstatus, ~] = system('where gh');
                    %[LFSstatus, ~] = system('where git-lfs');
                elseif ismac || islinux
                    [Gstatus, ~] = system('which git');
                    [GHstatus, ~] = system('which github.bat');
                    [CLIstatus, ~] = system('which gh');
                end

                %Check the status of github online. Is anyone signed in?
                [status, ~] = system('gh auth status --show-token');

                if status == 0 %somone is logged in
                    [~, user] = system('gh api user --jq ".login"');
                    app.ConnectGHButton.FontColor = [0, .5, 0];
                    app.ConnectGHButton.Tooltip = ['Logged in as ' user];
                end

                if Gstatus == 0
                    app.GitButton.BackgroundColor = [0.90,0.90,0.90];
                    app.GitButton.FontColor = [0.80,0.80,0.80];
                else %if not downloaded
                    app.Label.Visible = 'on';
                end
                if GHstatus == 0
                    app.GHDesktopButton.BackgroundColor = [0.90,0.90,0.90];
                    app.GHDesktopButton.FontColor = [0.80,0.80,0.80];
                end
                if CLIstatus == 0 %If it is downloaded
                    app.GHCLIButton.BackgroundColor = [0.90,0.90,0.90];
                    app.GHCLIButton.FontColor = [0.80,0.80,0.80];

                    app.CloneRepositoryButton.Enable = 'on';
                    app.ConnectGHButton.Enable = 'on';
                    app.LinkCodetoGithubButton.Enable = 'on';
                else %if not downloaded
                    app.Label.Visible = 'on';
                end
            end
        end

        % Button pushed function: GitButton
        function GitButtonPushed(app, event)
            reset(app);
            app.TextArea.FontColor = [0,0,0];
            app.TextArea.Value = "Installing Git...";
            drawnow;

            InstallProgram(app, 'Git');

            %Set up git username and email address.
            app.TextArea.Value = "Enter GitHub credentials: ";
            drawnow;

            app.emailEditField.Visible = 'on';
            app.usernameEditField.Visible = 'on';
            app.GHUsernameEditFieldLabel.Visible = 'on';
            app.GHemailaddressEditFieldLabel.Visible = 'on';
            app.OKButton1.Visible = 'on';
            app.OKButton1.Enable = 'on';
        end

        % Button pushed function: GHDesktopButton
        function GHDesktopButtonPushed(app, event)
            reset(app);
            app.TextArea.FontColor = [0,0,0];
            app.TextArea.Value = "Installing Github Desktop... ";
            drawnow;

            InstallProgram(app, 'Desktop');
        end

        % Button pushed function: OKButton1
        function OKButton1Pushed(app, event)
            %OK button for installing Git

            user = app.usernameEditField.Value;
            email = app.emailEditField.Value;

            command = ['git config --global user.name ' user];
            system(command);
            command = ['git config --global user.email ' email];
            system(command);

            app.TextArea.Value = 'GitHub credentials are set up.';
            drawnow;

            pause(3);

            app.TextArea.Value = 'If no other program is needed, please RESTART MATLAB.';
            app.TextArea.FontSize = 11;
            drawnow;
        end

        % Button pushed function: GHCLIButton
        function GHCLIButtonPushed(app, event)
            reset(app);
            app.TextArea.FontColor = [0,0,0];
            app.TextArea.Value = "Installing Github CLI... ";
            drawnow;

            InstallProgram(app, 'CLI');
        end

        % Button pushed function: CloneRepositoryButton
        function CloneRepositoryButtonPushed(app, event)
            reset(app);

            app.TextArea.FontColor = [0,0,0];
            app.TextArea.Value = "Enter repository URL: ";
            drawnow;

            app.userInput.Visible = 'on';
            app.OKButton2.Visible = 'on';
            app.OKButton2.Enable = 'on';
        end

        % Button pushed function: OKButton2
        function OKButton2Pushed(app, event)
            %Button to Confirm Clone

            URL = app.userInput.Value;

            command = ['git clone ' URL];
            [status, ~] = system(command);

            if status == 0
                app.TextArea.Value = 'Cloning Complete';
                drawnow
                reset(app);
            end
        end

        % Button pushed function: ConnectGHButton
        function ConnectGHButtonPushed(app, event)
            app.TextArea.FontColor = [0,0,0];
            reset(app);

            %Check the status of github online. Is anyone signed in?
            [status, ~] = system('gh auth status --show-token');

            if status == 0 %somone is logged in
                [~, user] = system('gh api user --jq ".login"');

                answer = questdlg(['You are currently logged in as ' user 'Do you want to switch users?'], ...
                    'Switch Users?', 'Yes', 'No', 'No');
                if strcmp(answer, 'No')
                    user = strtrim(user);
                    app.TextArea.FontColor = [0,0,0];
                    app.TextArea.Value = ['Currently logged in as:  ', user];
                    drawnow;
                    pause(3);
                    reset(app);
                    return;
                elseif strcmp(answer, 'Yes')
                    % Log the user out
                    system('gh auth logout -h github.com');
                    % Log new user in
                    app.TextArea.Value = "Enter code from the Command Window in browser...";
                    drawnow;
                    web('https://github.com/login/device');
                    system('gh auth login');

                    [~, user] = system('gh api user --jq ".login"');
                    app.TextArea.Value = ['Currently logged in as:  ', user];
                    drawnow;
                    return;
                end
            else
                % Run the system command
                app.TextArea.Value = "Enter code from the Command Window in browser...";
                drawnow;
                web('https://github.com/login/device');
                system('gh auth login');

                [~, user] = system('gh api user --jq ".login"');
                app.TextArea.Value = ['Currently logged in as:  ', user];
                drawnow;
            end

            %ensure toke is set to check private repos
            token = getenv('GITHUB_TOKEN');

            if isempty(token)
                [~, token] = system('gh auth token');
                token = strtrim(token);
                if ~isempty(token)
                    setenv('GITHUB_TOKEN', token);
                end
            end
            return;
        end

        % Button pushed function: LinkCodetoGithubButton
        function LinktoGHButtonPushed(app, event)
            app.TextArea.Value = 'Validating...';
            app.TextArea.FontColor = [0, 0, 0];
            drawnow;

            % Get User account
            [statusUser, userResult] = system('gh api user --jq ".login"');
            userLogin = strtrim(userResult);

            if statusUser ~= 0 || isempty(userLogin)
                % If no user found, don’t proceed
                uialert(app.UIFigure, ...
                    'You are not signed in. Please "Connect to Github".', ...
                    'Login Required');
                return;
            end
            %% Get organizations and user account
            [~, result] = system('gh api user/orgs --jq ".[].login"');

            orgs = strsplit(strtrim(result), '\n'); % split into list
            orgs = [{userLogin}, orgs]; %put user at the top

            % Populate owner dropdown
            app.OwnerDropDown.Items = orgs;
            app.OwnerDropDown.Value = userLogin; %default to personal account

            %See if code is already associated with github
            if exist('.git', 'dir')
                % Already a repo — check if it’s linked to a remote
                [~, remoteUrl] = system('git remote get-url origin');
                remoteUrl = strtrim(remoteUrl);

                if ~isempty(remoteUrl)
                    % Check if the remote actually exists
                    [status, ~] = system(['git ls-remote ', remoteUrl]);
                    if status == 0
                        app.TextArea.Value = ['Already linked to: ', remoteUrl];
                        app.TabGroup.SelectedTab = app.MainTab;
                        return;
                    else
                        %remote repo could not be found or has been removed
                        %therefore unlink it from local repo
                        system('git remote remove origin');
                        app.repoRemoved = true;
                    end
                end
            end

            app.TabGroup.SelectedTab = app.NewRepoTab; %switch to new repo tab
        end

        % Button pushed function: Create
        function CreatePushed(app, event)
            % --- Step 1: Input validation ---
            if isempty(app.OwnerDropDown.Value) || isempty(app.RepositoryNameEditField.Value)
                uialert(app.UIFigure, 'Please select an owner and/or enter a repository name.', 'Missing Input');
                return;
            elseif strcmpi(app.LicenseDropDown.Value, 'No License') && app.license.Value == 1
                uialert(app.UIFigure, 'Please select a license','Missing License Selection');
                return;
            end

            % Sanitize repository name
            rawName = strtrim(app.RepositoryNameEditField.Value);
            repoName = regexprep(rawName, '\s+', '-'); % spaces → dash
            if ~isempty(regexp(repoName, '[^A-Za-z0-9._-]', 'once')) || ...
                    startsWith(repoName, '-') || endsWith(repoName, '-')
                uialert(app.UIFigure, 'Invalid repository name.', 'Error');
                return;
            end

            repoFull = [app.OwnerDropDown.Value '/' repoName];

            % --- Step 2: Handle .gitignore modal ---
            if app.gitignore.Value == 1
                % Check if .gitignore already exists
                gitignorePath = fullfile(pwd, '.gitignore');
                if exist(gitignorePath, 'file')
                    % Read lines from existing .gitignore
                    fileContent = fileread(gitignorePath);
                    lines = strsplit(fileContent, {'\r','\n'}); % handle both line endings

                    % Remove empties and comments (#)
                    lines = strtrim(lines);
                    lines = lines(~cellfun('isempty', lines));
                    lines = lines(~startsWith(lines, '#'));

                    % Populate app.SelectedFiles with what’s already in .gitignore
                    app.SelectedFiles.Value = lines;
                end

                app.TabGroup.SelectedTab = app.IgnoreTab;
                uiwait(app.UIFigure); % wait until Done calls uiresume
            end

            % --- Step 3: Build gh repo create command ---
            createCmd = ['gh repo create ' repoFull];

            % Visibility
            visibility = app.VisibilityDropDown.Value; % e.g., 'Public' or 'Private'
            if strcmpi(visibility, 'public')
                createCmd = [createCmd ' --public'];
            else
                createCmd = [createCmd ' --private'];
            end

            % README option
            if app.README.Value == 1
                createCmd = [createCmd ' --add-readme'];
            end

            % if License option is selected
            if app.license.Value == 1
                % Mapping of display names to SPDX IDs
                licenseMap = containers.Map( ...
                    { 'Apache License 2.0', ...
                    'GNU General Public License v3.0', ...
                    'MIT License', ...
                    'BSD 2-Clause "Simplified" License', ...
                    'BSD 3-Clause "New" or "Revised" License', ...
                    'Boost Software License 1.0', ...
                    'Creative Commons Zero v1.0 Universal', ...
                    'Eclipse Public License 2.0', ...
                    'GNU Affero General Public License v3.0', ...
                    'GNU General Public License v2.0', ...
                    'GNU Lesser General Public License v2.1', ...
                    'Mozilla Public License 2.0', ...
                    'The Unlicense' }, ...
                    { 'apache-2.0', ...
                    'gpl-3.0', ...
                    'mit', ...
                    'bsd-2-clause', ...
                    'bsd-3-clause', ...
                    'bsl-1.0', ...
                    'cc0-1.0', ...
                    'epl-2.0', ...
                    'agpl-3.0', ...
                    'gpl-2.0', ...
                    'lgpl-2.1', ...
                    'mpl-2.0', ...
                    'unlicense' } ...
                    );

                % Example: get SPDX id for dropdown value
                licenseID = licenseMap(app.LicenseDropDown.Value);         % SPDX id

                createCmd = [createCmd ' --license ' licenseID];
            end

            % If there is a description
            descriptionText = strtrim(app.DescriptionEditField.Value);
            descriptionText = strrep(descriptionText, '"', '\"');

            if ~isempty(descriptionText)
                % Add description, wrapped in quotes
                createCmd = [createCmd, ' -d "', descriptionText, '"'];
            end

            % --- Step 3: Initialize local git repo if needed ---
            if ~exist('.git', 'dir')
                system('git init');
                % Copy MATLAB's default .gitattributes
                copyfile(fullfile(matlabroot, 'toolbox', 'shared', 'cmlink', 'git', ...
                    'auxiliary_files', 'mwgitattributes'), '.gitattributes');
            end

            app.TabGroup.SelectedTab = app.MainTab;

            % --- Step 4: Create GitHub repo ---
            app.TextArea.Value = 'Creating repository...';
            drawnow;

            [status, cmdout] = system(createCmd);

            if status ~= 0
                app.TextArea.Value = ['Failed to create repository:' cmdout];
                drawnow;
                pause(2);
                reset(app);
                return;
            end

            repoUrl = ['https://github.com/' repoFull];

            checkFileSize(app);

            % Stage + commit
            system('git add .');
            system('git commit -m "Initial commit"');

            % Set remote and push
            system('git branch -M main');
            system(['git remote add origin ' repoUrl]);

            if app.README.Value == 1
                system('git pull origin main --allow-unrelated-histories');
            end
            
            if app.repoRemoved == true
                [status, pushResult] = system('git push -u origin main --force');
            else
                [status, pushResult] = system('git push -u origin main');
            end
            

            if status == 0
                app.TabGroup.SelectedTab = app.MainTab;
                app.TextArea.Value = 'Files pushed successfully!';
            else
                app.TextArea.Value = ['Push failed: ' pushResult];
                system('git remote remove origin');
            end

            drawnow;

            %Reset NewRepo Fields
            app.nameAvailability.Text = '';
            app.charactersLabel.Text = '0/350 characters';
            app.RepositoryNameEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.VisibilityDropDown.Value = 'Public';
            app.gitignore.Value = 0;
            app.README.Value = 0;
            app.license.Value = 0;
            app.repoRemoved = false;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Write selected files into .gitignore
            gitignorePath = fullfile(pwd, '.gitignore');
            fid = fopen(gitignorePath, 'a'); % append so existing rules stay
            for i = 1:numel(app.SelectedFiles.Value)
                fprintf(fid, '%s\n', app.SelectedFiles.Value{i});
            end
            fclose(fid);

            uiresume(app.UIFigure);
        end

        % Value changed function: FileTypesListBox
        function FileTypesChanged(app, event)
            value = app.FileTypesListBox.Value; % this is a cell array if multiselect
            current = app.SelectedFiles.Value;  % get what’s already in the box

            % Ensure current is a cell array (if empty or string)
            if ischar(current)
                current = {current};
            elseif isempty(current)
                current = {};
            end

            % Remove any leading empty/blank entry
            current = current(~cellfun(@(c) isempty(strtrim(c)), current));

            % Add new selections
            for i = 1:numel(value)
                item = value{i};

                % Add only if not already in current
                if ~any(strcmp(current, item))
                    current{end+1} = item;
                end
            end
            % Update the box
            app.SelectedFiles.Value = current;
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            % Get everything in current folder
            items = dir(pwd);
            names = {};

            for k = 1:length(items)
                name = items(k).name;
                % Skip . and ..
                if ismember(name, {'.', '..'})
                    continue
                end
                % Append '/' if it's a folder
                if items(k).isdir
                    name = [name '/'];
                end
                % Add to list
                names{end+1} = name;
            end


            % Create selection dialog
            [indx,tf] = listdlg('PromptString','Select files/folders to ignore:', ...
                'SelectionMode','multiple', ...
                'ListString',names);

            %bring app window back
            figure(app.UIFigure);

            if tf
                current = app.SelectedFiles.Value;

                if isempty(current)
                    current = {};
                elseif ischar(current)
                    current = {current};
                end


                for i = 1:numel(indx)
                    sel = names{indx(i)};
                    if isempty(current) || (numel(current) == 1 && isempty(strtrim(current{1})))
                        current = {sel};
                    else
                        % If it’s a folder, add trailing slash
                        if items(indx(i)).isdir
                            sel = [sel '/'];
                        end
                        if ~any(strcmp(current, sel))
                            current{end+1} = sel;
                        end
                    end
                end
                app.SelectedFiles.Value = current;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Get text from the input field
            customType = strtrim(app.NotListedEdit.Value);

            if isempty(customType)
                return; % nothing entered
            end

            % Ensure it starts with a dot
            if ~startsWith(customType, '.')
                customType = ['.' customType];
            end

            % Get current list
            current = app.SelectedFiles.Value;
            if ischar(current), current = {current}; end
            if isempty(current), current = {}; end

            % If first line is just empty, replace it
            if isempty(current) || (numel(current) == 1 && isempty(strtrim(current{1})))
                current = {customType};
            else
                % Add only if not already included
                if ~any(strcmp(current, customType))
                    current{end+1} = customType;
                end
            end

            % Update SelectedFiles box
            app.SelectedFiles.Value = current;

            % Clear input field
            app.NotListedEdit.Value = '';
        end

        % Value changing function: RepositoryNameEditField
        function RepoNameChanging(app, event)
            rawName = strtrim(event.Value);

            % Step 1: replace spaces with dashes (GitHub style)
            repoName = regexprep(rawName, '\s+', '-');

            % Step 2: invalid characters
            if ~isempty(regexp(repoName, '[^A-Za-z0-9._-]', 'once'))
                app.nameAvailability.Text = ...
                    'Only contain letters, numbers, dots (.), dashes (-), and underscores (_).';
                app.nameAvailability.FontColor = [1 0 0]; % red
                return;
            end

            % Step 3: empty name
            if isempty(repoName)
                app.nameAvailability.Text = '⚠️ Name cannot be blank';
                app.nameAvailability.FontColor = [1 0 0]; % red
                return;
            end

            % Step 4: disallow leading/trailing dash
            if startsWith(repoName, '-') || endsWith(repoName, '-')
                app.nameAvailability.Text = 'Repository name cannot begin or end with a dash (-).';
                app.nameAvailability.FontColor = [1 0 0]; % red
                return;
            end

            % Step 5: length check
            if strlength(repoName) > 100
                app.nameAvailability.Text = 'Repository name cannot be longer than 100 characters.';
                app.nameAvailability.FontColor = [1 0 0];
                return;
            end

            % Step 6: Show live preview while checking availability
            app.nameAvailability.Text = 'Checking availability...';
            app.nameAvailability.FontColor = [0.5 0.5 0.5]; % gray

            drawnow; % update UI immediately

            % Step 7: Check GitHub availability

            %ensure toke is set to check private repos
            token = getenv('GITHUB_TOKEN');

            if isempty(token)
                [~, token] = system('gh auth token');
                token = strtrim(token);
                if ~isempty(token)
                    setenv('GITHUB_TOKEN', token);
                end
            end

            try
                options = weboptions( ...
                    'HeaderFields', {'Authorization', ['token ' token]}, ...
                    'Timeout', 10);

                owner = app.OwnerDropDown.Value; % assume you set this elsewhere
                url = ['https://api.github.com/repos/' owner '/' repoName];

                webread(url, options); % repo exists
                app.nameAvailability.Text = sprintf('⚠️ Repository "%s" already exists in %s.', repoName, owner);
                app.nameAvailability.FontColor = [1 0 0]; % red
            catch ME
                if contains(ME.message, '404')
                    app.nameAvailability.Text = sprintf('✅ Repository "%s" is available.', repoName);
                    app.nameAvailability.FontColor = [0 0.6 0]; % green
                else
                    % Other error (e.g. no internet)
                    app.nameAvailability.Text = '⚠️ Could not check availability.';
                    app.nameAvailability.FontColor = [0.8 0.5 0];
                end
            end

        end

        % Value changed function: license
        function licenseValueChanged(app, event)
            value = app.license.Value;

            if value == 1
                app.LicenseDropDown.Visible = 'on';
            else
                app.LicenseDropDown.Visible = 'off';
            end
        end

        % Value changing function: DescriptionEditField
        function DescriptionFieldChanging(app, event)
            % Get the current text while typing
            changingValue = event.Value;

            % Count number of characters
            numChars = strlength(changingValue);

            % Update label
            app.charactersLabel.Text = sprintf('%d/350 Characters', numChars);

            % Optional: change color if over 350
            if numChars > 350
                app.charactersLabel.FontColor = [1 0 0]; % red
            else
                app.charactersLabel.FontColor = [0 0 0]; % black
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.CloneRepositoryButton = uibutton(app.MainTab, 'push');
            app.CloneRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @CloneRepositoryButtonPushed, true);
            app.CloneRepositoryButton.Enable = 'off';
            app.CloneRepositoryButton.Text = 'Clone Repository';
            app.ConnectGHButton = uibutton(app.MainTab, 'push');
            app.ConnectGHButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectGHButtonPushed, true);
            app.ConnectGHButton.Enable = 'off';
            app.ConnectGHButton.Text = 'Connect to Github';
            app.LinkCodetoGithubButton = uibutton(app.MainTab, 'push');
            app.LinkCodetoGithubButton.ButtonPushedFcn = createCallbackFcn(app, @LinktoGHButtonPushed, true);
            app.LinkCodetoGithubButton.Enable = 'off';
            app.LinkCodetoGithubButton.Text = 'Link Code to Github';
            app.TextArea = uieditfield(app.MainTab, 'text');
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Please select an action:';
            app.userInput = uieditfield(app.MainTab, 'text');
            app.userInput.Visible = 'off';
            app.OKButton1 = uibutton(app.MainTab, 'push');
            app.OKButton1.ButtonPushedFcn = createCallbackFcn(app, @OKButton1Pushed, true);
            app.OKButton1.Visible = 'off';
            app.OKButton1.Text = 'OK';
            app.OKButton2 = uibutton(app.MainTab, 'push');
            app.OKButton2.ButtonPushedFcn = createCallbackFcn(app, @OKButton2Pushed, true);
            app.OKButton2.Visible = 'off';
            app.OKButton2.Text = 'OK';
            app.Label = uilabel(app.MainTab);
            app.Label.Visible = 'off';
            app.Label.Text = 'Need to Download Git and CLI';
            app.DownloadsPanel = uipanel(app.MainTab);
            app.DownloadsPanel.Tooltip = {''};
            app.DownloadsPanel.Title = 'Available Downloads';
            app.GitButton = uibutton(app.DownloadsPanel, 'push');
            app.GitButton.ButtonPushedFcn = createCallbackFcn(app, @GitButtonPushed, true);
            app.GitButton.Text = 'Git';
            app.GHDesktopButton = uibutton(app.DownloadsPanel, 'push');
            app.GHDesktopButton.ButtonPushedFcn = createCallbackFcn(app, @GHDesktopButtonPushed, true);
            app.GHDesktopButton.Text = 'Github Desktop';
            app.GHCLIButton = uibutton(app.DownloadsPanel, 'push');
            app.GHCLIButton.ButtonPushedFcn = createCallbackFcn(app, @GHCLIButtonPushed, true);
            app.GHCLIButton.Text = 'Github CLI';
            app.downloadslabel = uilabel(app.DownloadsPanel);
            app.downloadslabel.Text = 'If greyed out, program is already installed.';
            app.GHUsernameEditFieldLabel = uilabel(app.MainTab);
            app.GHUsernameEditFieldLabel.Visible = 'off';
            app.GHUsernameEditFieldLabel.Text = 'GH Username:';
            app.usernameEditField = uieditfield(app.MainTab, 'text');
            app.usernameEditField.Visible = 'off';
            app.GHemailaddressEditFieldLabel = uilabel(app.MainTab);
            app.GHemailaddressEditFieldLabel.Visible = 'off';
            app.GHemailaddressEditFieldLabel.Text = 'GH email address:';
            app.emailEditField = uieditfield(app.MainTab, 'text');
            app.emailEditField.Visible = 'off';
            app.NewRepoTab = uitab(app.TabGroup);
            app.NewRepoTab.Title = 'NewRepo';
            app.Create = uibutton(app.NewRepoTab, 'push');
            app.Create.ButtonPushedFcn = createCallbackFcn(app, @CreatePushed, true);
            app.Create.Text = 'Create';
            app.slash = uilabel(app.NewRepoTab);
            app.slash.Text = '/';
            app.DescriptionEditFieldLabel = uilabel(app.NewRepoTab);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.charactersLabel = uilabel(app.NewRepoTab);
            app.charactersLabel.Text = '0 / 350 characters';
            app.nameAvailability = uilabel(app.NewRepoTab);
            app.nameAvailability.Text = '';
            app.gitignore = uicheckbox(app.NewRepoTab);
            app.gitignore.Text = 'Add .gitignore';
            app.README = uicheckbox(app.NewRepoTab);
            app.README.Tooltip = {'READMEs can be used as longer descriptions'};
            app.README.Text = 'Add README';
            app.license = uicheckbox(app.NewRepoTab);
            app.license.ValueChangedFcn = createCallbackFcn(app, @licenseValueChanged, true);
            app.license.Tooltip = {'Licenses explain how others can use your code'};
            app.license.Text = 'Add license';
            app.LicenseDropDown = uidropdown(app.NewRepoTab);
            app.LicenseDropDown.Items = {'No license', 'Apache License 2.0', 'GNU General Public License v3.0', 'MIT License', 'BSD 2-Clause "Simplified" License', 'BSD 3-Clause "New" or "Revised" License', 'Boost Software License 1.0', 'Creative Commons Zero v1.0 Universal', 'Eclipse Public License 2.0', 'GNU Affero General Public License v3.0', 'GNU General Public License v2.0', 'GNU Lesser General Public License v2.1', 'Mozilla Public License 2.0', 'The Unlicense'};
            app.LicenseDropDown.Visible = 'off';
            app.LicenseDropDown.Value = 'No license';
            app.OwnerDropDown = uidropdown(app.NewRepoTab);
            app.OwnerDropDown.Items = {'', ''};
            app.OwnerDropDown.Value = '';
            app.OwnerDropDownLabel = uilabel(app.NewRepoTab);
            app.OwnerDropDownLabel.Text = 'Owner*';
            app.RepositoryNameEditFieldLabel = uilabel(app.NewRepoTab);
            app.RepositoryNameEditFieldLabel.Text = 'Repository Name*';
            app.RepositoryNameEditField = uieditfield(app.NewRepoTab, 'text');
            app.RepositoryNameEditField.ValueChangingFcn = createCallbackFcn(app, @RepoNameChanging, true);
            app.VisibilityLabel = uilabel(app.NewRepoTab);
            app.VisibilityLabel.Tooltip = {'Choose who can see and commit to this repository'};
            app.VisibilityLabel.Text = 'Visibility*';
            app.VisibilityDropDown = uidropdown(app.NewRepoTab);
            app.VisibilityDropDown.Items = {'Public', 'Private'};
            app.VisibilityDropDown.Value = 'Public';
            app.DescriptionEditField = uieditfield(app.NewRepoTab, 'text');
            app.DescriptionEditField.ValueChangingFcn = createCallbackFcn(app, @DescriptionFieldChanging, true);
            app.CreateanewrepositoryLabel = uilabel(app.NewRepoTab);
            app.CreateanewrepositoryLabel.Text = 'Create a new repository';
            app.Label_2 = uilabel(app.NewRepoTab);
            app.Label_2.Text = 'Repositories contain a project''s files and version history. (*) indicates a required field';
            app.IgnoreTab = uitab(app.TabGroup);
            app.IgnoreTab.Title = 'Ignore';
            app.SelectedFiles = uitextarea(app.IgnoreTab);
            app.SelectFilesButton = uibutton(app.IgnoreTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files/Folders';
            app.DoneButton = uibutton(app.IgnoreTab, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AddButton = uibutton(app.IgnoreTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.FiletypenotlistedEditFieldLabel = uilabel(app.IgnoreTab);
            app.FiletypenotlistedEditFieldLabel.Text = 'File type not listed';
            app.NotListedEdit = uieditfield(app.IgnoreTab, 'text');
            app.FileTypesListBoxLabel = uilabel(app.IgnoreTab);
            app.FileTypesListBoxLabel.Tooltip = {'If selected, all files with that extension will be ignored.'};
            app.FileTypesListBoxLabel.Text = 'File Types';
            app.FileTypesListBox = uilistbox(app.IgnoreTab);
            app.FileTypesListBox.Items = {'.7z', '.ai', '.apk', '.avi', '.bak', '.bat', '.bin', '.bmp', '.c', '.cab', '.cfg', '.class', '.com', '.cpp', '.cs', '.css', '.csv', '.cur', '.dat', '.dll', '.dmg', '.doc', '.docx', '.drv', '.exe', '.gif', '.h', '.htm', '.html', '.icns', '.ico', '.ini', '.iso', '.jar', '.java', '.jpeg', '.jpg', '.js', '.log', '.lnk', '.m4v', '.mov', '.mp3', '.mp4', '.mpeg', '.mpg', '.msi', '.pdf', '.php', '.pkg', '.png', '.pps', '.ppt', '.pptx', '.ps', '.psd', '.py', '.rar', '.rss', '.rtf', '.sh', '.svg', '.swift', '.sys', '.tar', '.tar.gz', '.tex', '.tif', '.tiff', '.tmp', '.txt', '.vb', '.wav', '.webm', '.webp', '.xls', '.xlsm', '.xlsx', '.xml', '.xhtml', '.zip'};
            app.FileTypesListBox.Multiselect = 'on';
            app.FileTypesListBox.ValueChangedFcn = createCallbackFcn(app, @FileTypesChanged, true);
            app.FileTypesListBox.Value = {};
            app.SelectwhichfilestoignoreLabel = uilabel(app.IgnoreTab);
            app.SelectwhichfilestoignoreLabel.Text = 'Select which files to ignore';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GitMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end